classdef project_gauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AscencionRecta1EditFieldLabel_4  matlab.ui.control.Label
        AscencionRecta1EditFieldLabel_3  matlab.ui.control.Label
        AscencionRecta1EditFieldLabel_2  matlab.ui.control.Label
        DatosObservacionalesLabel_2    matlab.ui.control.Label
        dec3_1                         matlab.ui.control.NumericEditField
        Declinacion2Label_2            matlab.ui.control.Label
        ar3_1                          matlab.ui.control.NumericEditField
        AscencionRecta2Label_2         matlab.ui.control.Label
        dec2_1                         matlab.ui.control.NumericEditField
        Declinacion2Label              matlab.ui.control.Label
        ar2_1                          matlab.ui.control.NumericEditField
        AscencionRecta2Label           matlab.ui.control.Label
        dec1_1                         matlab.ui.control.NumericEditField
        Declinacin1Label               matlab.ui.control.Label
        ar1_1                          matlab.ui.control.NumericEditField
        AscencionRecta1EditFieldLabel  matlab.ui.control.Label
        semiEjeMayor                   matlab.ui.control.NumericEditField
        excentricidad                  matlab.ui.control.NumericEditField
        inclinacion                    matlab.ui.control.NumericEditField
        dec3_2                         matlab.ui.control.NumericEditField
        dec3_3                         matlab.ui.control.NumericEditField
        ar3_2                          matlab.ui.control.NumericEditField
        ar3_3                          matlab.ui.control.NumericEditField
        dec2_2                         matlab.ui.control.NumericEditField
        dec2_3                         matlab.ui.control.NumericEditField
        ar2_2                          matlab.ui.control.NumericEditField
        ar2_3                          matlab.ui.control.NumericEditField
        dec1_2                         matlab.ui.control.NumericEditField
        dec1_3                         matlab.ui.control.NumericEditField
        ar1_2                          matlab.ui.control.NumericEditField
        ar1_3                          matlab.ui.control.NumericEditField
        DatosObservacionalesLabel      matlab.ui.control.Label
        GraficarButton                 matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            clc
            a1=0;
            a2=0;
            a3=0;
            d1=0;
            d2=0;
            d3=0;
            pyenv('Version', 'C:\Users\JOHANA\anaconda3\python.exe')
            format long
            %VALORES OBTENIDOS EN PYTHON
            deg=pyrunfile("codigo_gauss.py","deg");
            rad=pyrunfile("codigo_gauss.py","rad");
            mu= pyrunfile("codigo_gauss.py","mu");
            au=pyrunfile("codigo_gauss.py","au");
            e=23+26/60+12/3600; %ÁNGULO DE OBLICUIDAD  DE LA ECLÍPTICA
            t1=pyrunfile("codigo_gauss.py","t1");
            t2=pyrunfile("codigo_gauss.py","t2");
            t3=pyrunfile("codigo_gauss.py","t3");
            P1=pyrunfile("codigo_gauss.py","P1");
            P2=pyrunfile("codigo_gauss.py","P2");
            P3=pyrunfile("codigo_gauss.py","P3");
            
            %PEDIR DATOS DE OBSERVACIONES (PEDIR)
            a1 = [app.ar1_1.Value, app.ar1_2.Value, app.ar1_1.Value];
            d1 = [app.dec1_1.Value, app.dec1_2.Value, app.dec1_1.Value];
            a2 = [app.ar2_1.Value, app.ar2_2.Value, app.ar2_1.Value];
            d2 = [app.dec2_1.Value, app.dec2_2.Value, app.dec2_1.Value];
            a3 = [app.ar3_1.Value, app.ar3_2.Value, app.ar3_1.Value];
            d3 = [app.dec3_1.Value, app.dec3_2.Value, app.dec3_1.Value];
            
            %a1=[13 25 8.27];
            %d1=[-25 27 41.8];
            
            %a2=[14 49 25.66];
            %d2=[-23 59 55.9];
            
            %a3=[19 35 34.64];
            %d3=[-22 42 22.5];
            
            %DATOS OBSERVACIONALES
            alfa1=15*(a1(1)+a1(2)/60+a1(3)/3600);
            delta1=d1(1)+d1(2)/60+d1(3)/3600;
            
            alfa2=15*(a2(1)+a2(2)/60+a2(3)/3600);
            delta2=d2(1)+d2(2)/60+d2(3)/3600;
            
            alfa3=15*(a3(1)+a3(2)/60+a3(3)/3600);
            delta3=d3(1)+d3(2)/60+d3(3)/3600;
            
            %VECTORES UNITARIOS
            u1=[cos(delta1*deg)*cos(alfa1*deg) cos(delta1*deg)*sin(alfa1*deg) sin(delta1*deg)];
            u2=[cos(delta2*deg)*cos(alfa2*deg) cos(delta2*deg)*sin(alfa2*deg) sin(delta2*deg)];
            u3=[cos(delta3*deg)*cos(alfa3*deg) cos(delta3*deg)*sin(alfa3*deg) sin(delta3*deg)];
            
            %COEFICIENTES DE LAGRAGE
            r2=3*au; %Valor arbitrario
            
            f3=1-mu/(2*r2^3)*(t3-t2)^2;
            g3=(t3-t2)-mu/(6*r2^3)*(t3-t2)^3;
            
            f1=1-mu/(2*r2^3)*(t1-t2)^2;
            g1=(t1-t2)-mu/(6*r2^3)*(t1-t2)^3;
            
            %CONSTANTES
            c1=g3/(f1*g3-f3*g1);
            c3=-g1/(f1*g3-f3*g1);
            
            %VALOR DE PHO2
            P1=string(P1);
            P2=string(P2);
            P3=string(P3);
            
            rho2=(dot(str2double(P2),cross(u1,u3))-c1*dot(str2double(P1),cross(u1,u3))-c3*dot(str2double(P3),cross(u1,u3)))/dot(u2,cross(u1,u3));
            rho2/au;
            
            r2=sqrt(norm(str2double(P2))^2+rho2^2-2*rho2*dot(str2double(P2),u2));
            r2/au;
            
            %VERDADERO VALOR DE R2
            r2=3*au;
            for i=1:30
                f3=1-mu/(2*r2^3)*(t3-t2)^2;
                g3=(t3-t2)-mu/(6*r2^3)*(t3-t2)^3;
                
                f1=1-mu/(2*r2^3)*(t1-t2)^2;
                g1=(t1-t2)-mu/(6*r2^3)*(t1-t2)^3;
                
                c1=g3/(f1*g3-f3*g1);
                c3=-g1/(f1*g3-f3*g1);
                
                rho2=(dot(str2double(P2),cross(u1,u3))-c1*dot(str2double(P1),cross(u1,u3))-c3*dot(str2double(P3),cross(u1,u3)))/dot(u2,cross(u1,u3));
                r2=sqrt(norm(str2double(P2))^2+rho2^2-2*rho2*dot(str2double(P2),u2));
                r2/au;
            end
            
            %VALOR DE RHO1 Y RHO3
            rho1=(-dot(str2double(P2),cross(u2,u3))+c1*dot(str2double(P1),cross(u2,u3))+c3*dot(str2double(P3),cross(u2,u3)))/(c1*dot(u1,cross(u2,u3)));
            rho3=(-dot(str2double(P2),cross(u2,u1))+c1*dot(str2double(P1),cross(u2,u1))+c3*dot(str2double(P3),cross(u2,u1)))/(c3*dot(u3,cross(u2,u1)));
            rho1/au;
            rho3/au;
            
            %VALOR DE R1 Y R3
            r1=sqrt(norm(str2double(P1))^2+rho1^2-2*rho1*dot(str2double(P1),u1));
            r3=sqrt(norm(str2double(P3))^2+rho3^2-2*rho3*dot(str2double(P3),u3));
            r1/au;
            r3/au;
            
            
            %VECTORES EN COMPONENTES RECTANGULARES
            r1vec=(rho1*u1-str2double(P1));
            r2vec=(rho2*u2-str2double(P2));
            r3vec=(rho3*u3-str2double(P3));
            
            r1vec/au;
            r2vec/au;
            r3vec/au;
            
            %VECTOR DE VELOCIDAD
            v2vec=(-f3*r1vec+f1*r3vec)/(f1*g3-f3*g1);
            
            %CAMBIO AL SISTEMA ECLIPTICO
            [sx, sy, sz]=vectoresol(r1vec,e,deg);
            r1=[sx, sy, sz];
            r1/au;
            [sx, sy, sz]=vectoresol(r2vec,e,deg);
            r2=[sx, sy, sz];
            r2/au;
            [sx, sy, sz]=vectoresol(r3vec,e,deg);
            r3=[sx, sy, sz];
            r3/au;
            
            %velocidad
            v2=[v2vec(1) v2vec(2)*cos(e*deg)+v2vec(3)*sin(e*deg) v2vec(3)*cos(e*deg)-v2vec(2)*sin(e*deg)];
            
            %VECTORES PARA ELEMENTOS ORBITALES
            %MOMENTO ANGULAR
            h=cross(r2,v2);
            
            %VECTOR DE NODO
            k=[0 0 1];
            N=cross(k,h);
            
            %VECTOR EXCENTRICIDAD
            l=cross(v2,h)-mu*(r2/norm(r2));
            e=l/mu;
            
            %ELEMENTOS ORBITALES
            %Inclinación
            i=acos(h(3)/norm(h))*rad;
            
            % Excentricidad
            ex=norm(e);
            if ex>1
                ex=ex-1;
            end
            
            %Longitud de Nodo
            if N(2)>=0
               longNode = acos(N(1)/norm(N))*rad;
                if longNode<0
                    longNode = longNode + 360;
                end
            else
                longNode = 2 * pi-acos(N(1)/norm(N))*rad;
                if longNode<0
                    longNode = longNode + 360;
                end
            end
            
            %Argumento del perihelio
            if e(3)>=0 
               arg_perh = acos(dot(N,e)/(norm(N)*norm(e)))*rad;
                if arg_perh<0
                    arg_perh = arg_perh + 360;
                end
            else
                arg_perh = 2 * pi-acos(dot(N,e)/(norm(N)*norm(e)))*rad;
                if arg_perh<0
                    arg_perh = arg_perh + 360;
                end
            end
            
            
            %Anomalia verdadera,
            A=dot(e,r2);
            B=ex*norm(r2);
            tetha=acos(norm(A/B))*rad;
            
            %Semi-eje mayor
            %semi-latus rectum
            q=norm(h.^2/mu)/au;
            a=q/(1-ex^2);
            
            %GRÁFICA
            N = 200;
            M = linspace(0,2*pi,N);   
            alpha = zeros(1,N); 
            nTerms = 10;
            for j = 1:N
                % initialize eccentric anomaly to mean anomaly
                alpha(j) = M(j);
            
                % include first nTerms in infinite series
                for n = 1:nTerms
                    alpha(j) = alpha(j) + 2 / n * besselj(n,n*ex) .* sin(n*M(j));
                end
            end
            
            % calcualte polar coordiantes (theta, r) from eccentric anomaly
            theta = 2 * atan(sqrt((1+ex)/(1-ex)) * tan(alpha/2));
            r = a*(1-ex^2)./(1 + ex*cos(theta));
            x = a*ex + r.*cos(theta);
            y = r.*sin(theta);
            
            %Mostrar valor de los elementos orbitales en la interfaz
            app.inclinacion.Value = i;
            app.excentricidad.Value = ex;
            app.semiEjeMayor.Value = a;
            %figura(1);
            %plot(app.graph,x,y,'--b','LineWidth',2);
            plot(x,y,'--b','LineWidth',2)
            %axis(app.graph, 'padded');
            %xlim([-1.2*a,1.2*a]);
            %ylim([-1.2*a,1.2*a]);
            set(gca,'Color','k');
            hold on;
            %hold(app.graph, 'on');
            % Poner el foco
            %plot(app.graph,a*ex,0,'yo','MarkerSize',10,'MarkerFaceColor','y');
            plot(a*ex,0,'yo','MarkerSize',10,'MarkerFaceColor','y');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2022-11-24 at 11.01.51 AM.jpeg');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar Orbita';
            app.DatosObservacionalesLabel = uilabel(app.UIFigure);
            app.DatosObservacionalesLabel.Text = 'Datos Observacionales';
            app.ar1_3 = uieditfield(app.UIFigure, 'numeric');
            app.ar1_2 = uieditfield(app.UIFigure, 'numeric');
            app.dec1_3 = uieditfield(app.UIFigure, 'numeric');
            app.dec1_2 = uieditfield(app.UIFigure, 'numeric');
            app.ar2_3 = uieditfield(app.UIFigure, 'numeric');
            app.ar2_2 = uieditfield(app.UIFigure, 'numeric');
            app.dec2_3 = uieditfield(app.UIFigure, 'numeric');
            app.dec2_2 = uieditfield(app.UIFigure, 'numeric');
            app.ar3_3 = uieditfield(app.UIFigure, 'numeric');
            app.ar3_2 = uieditfield(app.UIFigure, 'numeric');
            app.dec3_3 = uieditfield(app.UIFigure, 'numeric');
            app.dec3_2 = uieditfield(app.UIFigure, 'numeric');
            app.inclinacion = uieditfield(app.UIFigure, 'numeric');
            app.excentricidad = uieditfield(app.UIFigure, 'numeric');
            app.semiEjeMayor = uieditfield(app.UIFigure, 'numeric');
            app.AscencionRecta1EditFieldLabel = uilabel(app.UIFigure);
            app.AscencionRecta1EditFieldLabel.Text = 'Ascencion Recta  1';
            app.ar1_1 = uieditfield(app.UIFigure, 'numeric');
            app.Declinacin1Label = uilabel(app.UIFigure);
            app.Declinacin1Label.Text = 'Declinación  1';
            app.dec1_1 = uieditfield(app.UIFigure, 'numeric');
            app.AscencionRecta2Label = uilabel(app.UIFigure);
            app.AscencionRecta2Label.Text = 'Ascencion Recta  2';
            app.ar2_1 = uieditfield(app.UIFigure, 'numeric');
            app.Declinacion2Label = uilabel(app.UIFigure);
            app.Declinacion2Label.Text = 'Declinación  2';
            app.dec2_1 = uieditfield(app.UIFigure, 'numeric');
            app.AscencionRecta2Label_2 = uilabel(app.UIFigure);
            app.AscencionRecta2Label_2.Text = 'Ascencion Recta  3';
            app.ar3_1 = uieditfield(app.UIFigure, 'numeric');
            app.Declinacion2Label_2 = uilabel(app.UIFigure);
            app.Declinacion2Label_2.Text = 'Declinación  3';
            app.dec3_1 = uieditfield(app.UIFigure, 'numeric');
            app.DatosObservacionalesLabel_2 = uilabel(app.UIFigure);
            app.DatosObservacionalesLabel_2.Text = 'Datos Observacionales';
            app.AscencionRecta1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.AscencionRecta1EditFieldLabel_2.Text = 'Inclinación (deg)';
            app.AscencionRecta1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.AscencionRecta1EditFieldLabel_3.Text = 'Semieje Mayor (AU)';
            app.AscencionRecta1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.AscencionRecta1EditFieldLabel_4.Text = 'Excentricidad ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_gauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end